classdef AdeeptArmGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdeeptArmGUIUIFigure     matlab.ui.Figure
        ValuePanel_2             matlab.ui.container.Panel
        SAVEButton_3             matlab.ui.control.Button
        GOButton_4               matlab.ui.control.Button
        Label_3                  matlab.ui.control.Label
        SAVEButton_2             matlab.ui.control.Button
        GOButton_3               matlab.ui.control.Button
        Label_2                  matlab.ui.control.Label
        SAVEButton               matlab.ui.control.Button
        GOButton_2               matlab.ui.control.Button
        Label                    matlab.ui.control.Label
        ValuePanel               matlab.ui.container.Panel
        HOMEButton               matlab.ui.control.Button
        GOButton                 matlab.ui.control.Button
        J3ValueEditField         matlab.ui.control.NumericEditField
        J3ValueEditFieldLabel    matlab.ui.control.Label
        J2ValueEditField         matlab.ui.control.NumericEditField
        J2ValueEditField_2Label  matlab.ui.control.Label
        J1ValueEditField         matlab.ui.control.NumericEditField
        J1ValueEditFieldLabel    matlab.ui.control.Label
        SliderPanel              matlab.ui.container.Panel
        Joint3Slider             matlab.ui.control.Slider
        Joint3SliderLabel        matlab.ui.control.Label
        Joint2Slider             matlab.ui.control.Slider
        Joint2SliderLabel        matlab.ui.control.Label
        Joint1Slider             matlab.ui.control.Slider
        Joint1SliderLabel        matlab.ui.control.Label
        axes_arm                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices

        jointAngles = [0 0 0];

        savedAngles1 = [0 0 0];
        savedAngles2 = [0 0 0];
        savedAngles3 = [0 0 0];
    end
    
    methods (Access = private)
        
        function updateArm(app)

            % Create the A homogeneous transformation matrices for the given jointAngles.
            A1 = create_A_matrix(64, 0, 0, app.jointAngles(1));
            A2 = create_A_matrix(0, 0, -90, app.jointAngles(2));
            A3 = create_A_matrix(0, 140, 0, app.jointAngles(3));
            
            % Use the current A matricies to form the T0_n matricies.
            T0_1 = A1;
            T0_2 = A1 * A2;
            T0_3 = A1 * A2 * A3;
            
            % Use the current T matricies to transform the original patch vertices
            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;
            
            % Update the patches with the newly transformed vertices
            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');
            

            app.Label.Text = string(app.savedAngles1(1) + ", " + app.savedAngles1(2) + ", " + app.savedAngles1(3));
            app.Label_2.Text = string(app.savedAngles2(1) + ", " + app.savedAngles2(2) + ", " + app.savedAngles2(3));
            app.Label_3.Text = string(app.savedAngles3(1) + ", " + app.savedAngles3(2) + ", " + app.savedAngles3(3));

            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);

            app.J1ValueEditField.Value = app.jointAngles(1);
            app.J2ValueEditField.Value = app.jointAngles(2);
            app.J3ValueEditField.Value = app.jointAngles(3);

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            clc

            % Prepare the axes_arm properties
            app.axes_arm.View = [90 -50];
            app.axes_arm.Visible = 'off';
            
            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
            % app.axes_arm.XLim = [-100 100];
            % app.axes_arm.YLim = [-150 150];
            % app.axes_arm.ZLim = [-22 22];
            
            % Create vertices for all the patches
            makeStlLink("AdeeptL0.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("AdeeptL1.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));
            
            app.link2Patch = makeStlLink("AdeeptL2.stl", app.axes_arm, [198/255 172/255 20/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));
            
            app.link3Patch = makeStlLink("AdeeptL3.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));

            app.updateArm()

        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;

            app.jointAngles(1) = changingValue;
            app.updateArm()
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;

            app.jointAngles(2) = changingValue;
            app.updateArm()
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;

            app.jointAngles(3) = changingValue;
            app.updateArm()
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            app.jointAngles = [app.J1ValueEditField.Value, app.J2ValueEditField.Value, app.J3ValueEditField.Value];
            
            app.updateArm()
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            app.jointAngles = [0, 0, 0];

            app.updateArm()
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            app.savedAngles1 = app.jointAngles;
            app.Label.Text = string(app.savedAngles1(1) + ", " + app.savedAngles1(2) + ", " + app.savedAngles1(3));
        end

        % Button pushed function: GOButton_2
        function GOButton_2Pushed(app, event)
            app.jointAngles = app.savedAngles1;
            app.updateArm()
        end

        % Button pushed function: SAVEButton_2
        function SAVEButton_2Pushed(app, event)
            app.savedAngles2 = app.jointAngles;
            app.Label_2.Text = string(app.savedAngles2(1) + ", " + app.savedAngles2(2) + ", " + app.savedAngles2(3));
        end

        % Button pushed function: GOButton_3
        function GOButton_3Pushed(app, event)
            app.jointAngles = app.savedAngles2;
            app.updateArm()
        end

        % Button pushed function: SAVEButton_3
        function SAVEButton_3Pushed(app, event)
            app.savedAngles3 = app.jointAngles;
            app.Label_3.Text = string(app.savedAngles3(1) + ", " + app.savedAngles3(2) + ", " + app.savedAngles3(3));
        end

        % Button pushed function: GOButton_4
        function GOButton_4Pushed(app, event)
            app.jointAngles = app.savedAngles3;
            app.updateArm()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdeeptArmGUIUIFigure = uifigure('Visible', 'off');
            app.AdeeptArmGUIUIFigure.Name = 'Adeept Arm GUI';
            app.axes_arm = uiaxes(app.AdeeptArmGUIUIFigure);
            title(app.axes_arm, 'Title')
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.SliderPanel = uipanel(app.AdeeptArmGUIUIFigure);
            app.SliderPanel.Title = 'Sliders';
            app.Joint1SliderLabel = uilabel(app.SliderPanel);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.SliderPanel);
            app.Joint1Slider.Limits = [-90 90];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.SliderPanel);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.SliderPanel);
            app.Joint2Slider.Limits = [-90 90];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.SliderPanel);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.SliderPanel);
            app.Joint3Slider.Limits = [-90 90];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.ValuePanel = uipanel(app.AdeeptArmGUIUIFigure);
            app.ValuePanel.Title = 'Numeric Entry';
            app.J1ValueEditFieldLabel = uilabel(app.ValuePanel);
            app.J1ValueEditFieldLabel.Text = 'J1 Value';
            app.J1ValueEditField = uieditfield(app.ValuePanel, 'numeric');
            app.J2ValueEditField_2Label = uilabel(app.ValuePanel);
            app.J2ValueEditField_2Label.Text = 'J2 Value';
            app.J2ValueEditField = uieditfield(app.ValuePanel, 'numeric');
            app.J3ValueEditFieldLabel = uilabel(app.ValuePanel);
            app.J3ValueEditFieldLabel.Text = 'J3 Value';
            app.J3ValueEditField = uieditfield(app.ValuePanel, 'numeric');
            app.GOButton = uibutton(app.ValuePanel, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.HOMEButton = uibutton(app.ValuePanel, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.ValuePanel_2 = uipanel(app.AdeeptArmGUIUIFigure);
            app.ValuePanel_2.Title = 'Save Position';
            app.Label = uilabel(app.ValuePanel_2);
            app.GOButton_2 = uibutton(app.ValuePanel_2, 'push');
            app.GOButton_2.ButtonPushedFcn = createCallbackFcn(app, @GOButton_2Pushed, true);
            app.GOButton_2.Text = 'GO';
            app.SAVEButton = uibutton(app.ValuePanel_2, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.Label_2 = uilabel(app.ValuePanel_2);
            app.GOButton_3 = uibutton(app.ValuePanel_2, 'push');
            app.GOButton_3.ButtonPushedFcn = createCallbackFcn(app, @GOButton_3Pushed, true);
            app.GOButton_3.Text = 'GO';
            app.SAVEButton_2 = uibutton(app.ValuePanel_2, 'push');
            app.SAVEButton_2.ButtonPushedFcn = createCallbackFcn(app, @SAVEButton_2Pushed, true);
            app.SAVEButton_2.Text = 'SAVE';
            app.Label_3 = uilabel(app.ValuePanel_2);
            app.GOButton_4 = uibutton(app.ValuePanel_2, 'push');
            app.GOButton_4.ButtonPushedFcn = createCallbackFcn(app, @GOButton_4Pushed, true);
            app.GOButton_4.Text = 'GO';
            app.SAVEButton_3 = uibutton(app.ValuePanel_2, 'push');
            app.SAVEButton_3.ButtonPushedFcn = createCallbackFcn(app, @SAVEButton_3Pushed, true);
            app.SAVEButton_3.Text = 'SAVE';
            % Show the figure after all components are created
            app.AdeeptArmGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdeeptArmGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdeeptArmGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdeeptArmGUIUIFigure)
        end
    end
end