classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ExtendBarsImage       matlab.ui.control.Image
        MovePlatePanel        matlab.ui.container.Panel
        GridLayout2_4         matlab.ui.container.GridLayout
        MoveButton            matlab.ui.control.Button
        ToField               matlab.ui.control.EditField
        FromField             matlab.ui.control.EditField
        DanceButton           matlab.ui.control.Button
        GripperPositionPanel  matlab.ui.container.Panel
        GridLayout2_2         matlab.ui.container.GridLayout
        XAxis5Button          matlab.ui.control.Button
        XAxis4Button          matlab.ui.control.Button
        XAxis3Button          matlab.ui.control.Button
        XAxis2Button          matlab.ui.control.Button
        XAxis1Button          matlab.ui.control.Button
        ResetButton           matlab.ui.control.Button
        TimeTablePanel        matlab.ui.container.Panel
        GridLayout2_5         matlab.ui.container.GridLayout
        UITable2              matlab.ui.control.Table
        GripperPanel          matlab.ui.container.Panel
        GridLayout2           matlab.ui.container.GridLayout
        OpenButton            matlab.ui.control.Button
        CloseButton           matlab.ui.control.Button
        COMPanel              matlab.ui.container.Panel
        GridLayout2_6         matlab.ui.container.GridLayout
        DisconnectButton      matlab.ui.control.Button
        ConnectButton         matlab.ui.control.Button
        ComPortField          matlab.ui.control.EditField
        ZAaxisPanel           matlab.ui.container.Panel
        GridLayout2_3         matlab.ui.container.GridLayout
        RetractButton         matlab.ui.control.Button
        ExtendButton          matlab.ui.control.Button
        GripperImage          matlab.ui.control.Image
        BackgroundImage       matlab.ui.control.Image
    end

    
    properties (Access = private)
        Loader

        ExtendBarsPositions
        GripperRetractedPositions
        GripperExtendedPositions
    end
    
    methods (Access = private)
        
        function XAxis(app, loc)
            response = app.Loader.x(loc);
            disp(response)

            app.GripperImage.Position = app.GripperRetractedPositions(app.Loader.xAxisPosition, :);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            app.ExtendBarsPositions = [
                    57,  279, 110, 100;
                    135, 279, 110, 100;
                    217, 279, 110, 100;
                    295, 279, 110, 100;
                    378, 279, 110, 100
                ];

            app.GripperRetractedPositions = [
                    57,  330, 110, 50;
                    135, 330, 110, 50;
                    217, 330, 110, 50;
                    295, 330, 110, 50;
                    378, 330, 110, 50
                ];
            
            app.GripperExtendedPositions = [
                    57,  230, 110, 50;
                    135, 230, 110, 50;
                    217, 230, 110, 50;
                    295, 230, 110, 50;
                    378, 230, 110, 50
                ];
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            portStr = str2double(app.ComPortField.Value);
            disp(portStr)
            
            app.Loader = PlateLoader(portStr);

            app.ExtendBarsImage.Visible = "off";
            app.GripperImage.Position = app.GripperRetractedPositions(app.Loader.xAxisPosition, :);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            response = app.Loader.shutdown();

            disp(response)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            response = app.Loader.reset();
            disp(response)

            app.ExtendBarsImage.Visible = "off";
            app.GripperImage.Position = app.GripperRetractedPositions(app.Loader.xAxisPosition, :);
            app.GripperImage.ImageSource = "Lab2 Images/gripper_closed_no_plate.jpg";
        end

        % Button pushed function: XAxis1Button, XAxis2Button, 
        % ...and 3 other components
        function XAxisButtonPushed(app, event)
            targetPos = str2double(event.Source.Text);
            app.XAxis(targetPos);

            app.ExtendBarsImage.Visible = "off";
        end

        % Button pushed function: ExtendButton
        function ExtendButtonPushed(app, event)
            response = app.Loader.extend();
            disp(response)

            app.ExtendBarsImage.Visible = "on";
            app.ExtendBarsImage.Position = app.ExtendBarsPositions(app.Loader.xAxisPosition, :);
            app.GripperImage.Position = app.GripperExtendedPositions(app.Loader.xAxisPosition, :);
        end

        % Button pushed function: RetractButton
        function RetractButtonPushed(app, event)
            response = app.Loader.retract();
            disp(response)

            app.ExtendBarsImage.Visible = "off";
            app.GripperImage.Position = app.GripperRetractedPositions(app.Loader.xAxisPosition, :);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            response = app.Loader.open();
            disp(response)

            app.GripperImage.ImageSource = "Lab2 Images/gripper_open_no_plate.jpg";
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            response = app.Loader.close();
            disp(response)

            if (app.Loader.isPlatePresent)
                app.GripperImage.ImageSource = "Lab2 Images/gripper_with_plate.jpg";
            else
                app.GripperImage.ImageSource = "Lab2 Images/gripper_closed_no_plate.jpg";
            end
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            from = str2double(app.FromField.Value);
            to = str2double(app.ToField.Value);
            
            response = app.Loader.movePlate(from, to);
            disp(response)

            app.ExtendBarsImage.Visible = "off";
            app.GripperImage.Position = app.GripperRetractedPositions(app.Loader.xAxisPosition, :);
            app.GripperImage.ImageSource = "Lab2 Images/gripper_closed_no_plate.jpg";
        end

        % Button pushed function: DanceButton
        function DanceButtonPushed(app, event)
            app.Loader.dance();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BackgroundImage = uiimage(app.UIFigure);
            app.BackgroundImage.ImageSource = fullfile(pathToMLAPP, 'Lab2 Images', 'robot_background.jpg');
            app.GripperImage = uiimage(app.UIFigure);
            app.GripperImage.ImageSource = fullfile(pathToMLAPP, 'Lab2 Images', 'gripper_open_no_plate.jpg');
            app.ZAaxisPanel = uipanel(app.UIFigure);
            app.ZAaxisPanel.Title = 'Z-Aaxis';
            app.GridLayout2_3 = uigridlayout(app.ZAaxisPanel);
            app.ExtendButton = uibutton(app.GridLayout2_3, 'push');
            app.ExtendButton.ButtonPushedFcn = createCallbackFcn(app, @ExtendButtonPushed, true);
            app.ExtendButton.Text = 'Extend';
            app.RetractButton = uibutton(app.GridLayout2_3, 'push');
            app.RetractButton.ButtonPushedFcn = createCallbackFcn(app, @RetractButtonPushed, true);
            app.RetractButton.Text = 'Retract';
            app.COMPanel = uipanel(app.UIFigure);
            app.COMPanel.Title = 'COM';
            app.GridLayout2_6 = uigridlayout(app.COMPanel);
            app.ComPortField = uieditfield(app.GridLayout2_6, 'text');
            app.ComPortField.Placeholder = 'COM Port Number';
            app.ConnectButton = uibutton(app.GridLayout2_6, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.GridLayout2_6, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.GripperPanel = uipanel(app.UIFigure);
            app.GripperPanel.Title = 'Gripper';
            app.GridLayout2 = uigridlayout(app.GripperPanel);
            app.CloseButton = uibutton(app.GridLayout2, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.GridLayout2, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.TimeTablePanel = uipanel(app.UIFigure);
            app.TimeTablePanel.Title = 'Time Table';
            app.GridLayout2_5 = uigridlayout(app.TimeTablePanel);
            app.UITable2 = uitable(app.GridLayout2_5);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {'row1, row2, row3, row4'};
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.GripperPositionPanel = uipanel(app.UIFigure);
            app.GripperPositionPanel.Title = 'Gripper Position';
            app.GridLayout2_2 = uigridlayout(app.GripperPositionPanel);
            app.XAxis1Button = uibutton(app.GridLayout2_2, 'push');
            app.XAxis1Button.ButtonPushedFcn = createCallbackFcn(app, @XAxisButtonPushed, true);
            app.XAxis1Button.Text = '1';
            app.XAxis2Button = uibutton(app.GridLayout2_2, 'push');
            app.XAxis2Button.ButtonPushedFcn = createCallbackFcn(app, @XAxisButtonPushed, true);
            app.XAxis2Button.Text = '2';
            app.XAxis3Button = uibutton(app.GridLayout2_2, 'push');
            app.XAxis3Button.ButtonPushedFcn = createCallbackFcn(app, @XAxisButtonPushed, true);
            app.XAxis3Button.Text = '3';
            app.XAxis4Button = uibutton(app.GridLayout2_2, 'push');
            app.XAxis4Button.ButtonPushedFcn = createCallbackFcn(app, @XAxisButtonPushed, true);
            app.XAxis4Button.Text = '4';
            app.XAxis5Button = uibutton(app.GridLayout2_2, 'push');
            app.XAxis5Button.ButtonPushedFcn = createCallbackFcn(app, @XAxisButtonPushed, true);
            app.XAxis5Button.Text = '5';
            app.DanceButton = uibutton(app.UIFigure, 'push');
            app.DanceButton.ButtonPushedFcn = createCallbackFcn(app, @DanceButtonPushed, true);
            app.DanceButton.Text = 'Dance';
            app.MovePlatePanel = uipanel(app.UIFigure);
            app.MovePlatePanel.Title = 'Move Plate';
            app.GridLayout2_4 = uigridlayout(app.MovePlatePanel);
            app.FromField = uieditfield(app.GridLayout2_4, 'text');
            app.FromField.Placeholder = 'From';
            app.ToField = uieditfield(app.GridLayout2_4, 'text');
            app.ToField.Placeholder = 'To';
            app.MoveButton = uibutton(app.GridLayout2_4, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.ExtendBarsImage = uiimage(app.UIFigure);
            app.ExtendBarsImage.ImageSource = fullfile(pathToMLAPP, 'Lab2 Images', 'extended_bars.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end